classdef GoldStandardApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DeleteButton                matlab.ui.control.Button
        AltitudeImageButton         matlab.ui.control.Button
        ReflectanceImageButton      matlab.ui.control.Button
        StoreButton                 matlab.ui.control.Button
        MarkButton                  matlab.ui.control.Button
        MinPatchHeightSpinner       matlab.ui.control.Spinner
        MinPatchHeightSpinnerLabel  matlab.ui.control.Label
        MinPatchWidthSpinner        matlab.ui.control.Spinner
        MinPatchWidthSpinnerLabel   matlab.ui.control.Label
        LoadImageButton             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        trenchesList % Description
        drawAxes % Description
        imageWidth
        imageHeight
        mask
        altitude
        vis1
        vis2
        vis5
        vis4
        vis3
        nir3
        nir4
        nir1
        nir2
        sourceFileName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imageWidth = 2048;
            app.imageHeight = 2048;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [app.sourceFileName, path]= uigetfile('*.img');

            fid = fopen(fullfile(path, app.sourceFileName));
            app.altitude = fread(fid,[app.imageHeight app.imageWidth],'single',0,'b');
            app.vis1 = fread(fid,[app.imageHeight app.imageWidth],'int16',0,'b');
            app.vis2 = fread(fid,[app.imageHeight app.imageWidth],'int16',0,'b');
            app.vis5 = fread(fid,[app.imageHeight app.imageWidth],'int16',0,'b');
            app.vis4 = fread(fid,[app.imageHeight app.imageWidth],'int16',0,'b');
            app.vis3 = fread(fid,[app.imageHeight app.imageWidth],'int16',0,'b');
            app.nir3 = fread(fid,[app.imageHeight app.imageWidth],'int16',0,'b');
            app.nir4 = fread(fid,[app.imageHeight app.imageWidth],'int16',0,'b');
            app.nir1 = fread(fid,[app.imageHeight app.imageWidth],'int16',0,'b');
            app.nir2 = fread(fid,[app.imageHeight app.imageWidth],'int16',0,'b');
            fclose(fid);
            
            app.UIAxes.Title.String = app.sourceFileName;
            app.UIAxes.XLim = [0 size(app.altitude,2)];
            app.UIAxes.YLim = [0 size(app.altitude,1)];
            imagesc(app.UIAxes,app.altitude);
%           %app.drawAxes = axes('Parent', app.UIFigure);
            app.trenchesList = cell({});
            app.mask = zeros(app.imageHeight, app.imageWidth);
        end

        % Button pushed function: MarkButton
        function MarkButtonPushed(app, event)
            %app.trenchesList(end+1) = drawassisted(app.drawAxes);
            app.trenchesList(end+1) = {drawpolygon(app.UIAxes)};
            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if ~isempty(app.trenchesList)
                app.trenchesList = app.trenchesList(1:end-1);
            end
        end

        % Button pushed function: StoreButton
        function StoreButtonPushed(app, event)
            
            if(~isfolder(['sinuous-rille-' num2str(app.MinPatchHeightSpinner.Value) '-'  num2str(app.MinPatchWidthSpinner.Value)]))
                mkdir(['Patchpixeltest\sinuous-rille-' num2str(app.MinPatchHeightSpinner.Value) '-'  num2str(app.MinPatchWidthSpinner.Value)]);
            end
            if(~isfolder(['no-sinuous-rille-' num2str(app.MinPatchHeightSpinner.Value) '-'  num2str(app.MinPatchWidthSpinner.Value)]))
                mkdir(['Patchpixeltest\no-sinuous-rille-' num2str(app.MinPatchHeightSpinner.Value) '-'  num2str(app.MinPatchWidthSpinner.Value)]);
            end
            if(~isfolder('maskpixeltest'))
                mkdir('maskpixeltest');
            end
            
            children = get(app.UIAxes,'children');
            app.mask = zeros(app.imageHeight,app.imageWidth);
            for i = 1:numel(children)
                if strcmp(children(i).Type,'images.roi.polygon')
                    app.mask = app.mask + poly2mask(children(i).Position(:,1),children(i).Position(:,2),app.imageWidth,app.imageHeight);
                end
            end
            mask = app.mask;
            %define the folder where you want to save your masks
            save(['.\maskpixeltest\' app.sourceFileName(1:end-4) '_mask.mat'],'mask');

            noPatchesHorizontal = floor(app.imageWidth / app.MinPatchWidthSpinner.Value);
            noPatchesVertical = floor(app.imageHeight / app.MinPatchHeightSpinner.Value);
            
            for x = 1:noPatchesHorizontal
                for y = 1:noPatchesVertical
                    patchLocation = logical(zeros(app.imageHeight, app.imageWidth));
                    xmin=(x-1)*app.MinPatchWidthSpinner.Value+1;
                    xmax=x*app.MinPatchWidthSpinner.Value;
                    ymin=(y-1)*app.MinPatchHeightSpinner.Value+1;
                    ymax=y*app.MinPatchHeightSpinner.Value;
                    patchLocation(ymin:ymax,xmin:xmax) = 1;
                    
                    if sum(sum(app.mask(patchLocation))) > 50
                        % make rille
                        type = 'sinuous-rille';
                    else
                        % make no-rille
                        type = 'no-sinuous-rille';
                    end

                    %define the folder of your choice to save the patches already segregated in the rille and non-rille folders    
                    destinationFilePath = ['.\Patchpixeltest\' type '-' num2str(app.MinPatchHeightSpinner.Value) '-'  num2str(app.MinPatchWidthSpinner.Value) '\' app.sourceFileName(1:end-4) '-' num2str(y) '-' num2str(x) '.img'];
                    fid = fopen(destinationFilePath,'w');
                    if fid
                        fwrite(fid,app.altitude(ymin:ymax,xmin:xmax),'single',0,'b');
                        fwrite(fid,app.vis1(ymin:ymax,xmin:xmax),'int16',0,'b');
                        fwrite(fid,app.vis2(ymin:ymax,xmin:xmax),'int16',0,'b');
                        fwrite(fid,app.vis5(ymin:ymax,xmin:xmax),'int16',0,'b');
                        fwrite(fid,app.vis4(ymin:ymax,xmin:xmax),'int16',0,'b');
                        fwrite(fid,app.vis3(ymin:ymax,xmin:xmax),'int16',0,'b');
                        fwrite(fid,app.nir3(ymin:ymax,xmin:xmax),'int16',0,'b');
                        fwrite(fid,app.nir4(ymin:ymax,xmin:xmax),'int16',0,'b');
                        fwrite(fid,app.nir1(ymin:ymax,xmin:xmax),'int16',0,'b');
                        fwrite(fid,app.nir2(ymin:ymax,xmin:xmax),'int16',0,'b');
                        fclose(fid);
                    end
                end
            end

        end

        % Button pushed function: ReflectanceImageButton
        function switchToReflectance(app, event)
            app.UIAxes.XLim = [0 size(app.vis1,2)];
            app.UIAxes.YLim = [0 size(app.vis1,1)];
            imagesc(app.UIAxes,app.vis1);
        end

        % Button pushed function: AltitudeImageButton
        function switchToAltitude(app, event)
            app.UIAxes.XLim = [0 size(app.altitude,2)];
            app.UIAxes.YLim = [0 size(app.altitude,1)];
            imagesc(app.UIAxes,app.altitude);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.MinPatchWidthSpinnerLabel = uilabel(app.UIFigure);
            app.MinPatchWidthSpinnerLabel.Text = 'Min. Patch Width';
            app.MinPatchWidthSpinner = uispinner(app.UIFigure);
            app.MinPatchWidthSpinner.Value = 50;
            app.MinPatchHeightSpinnerLabel = uilabel(app.UIFigure);
            app.MinPatchHeightSpinnerLabel.Text = 'Min. Patch Height';
            app.MinPatchHeightSpinner = uispinner(app.UIFigure);
            app.MinPatchHeightSpinner.Value = 50;
            app.MarkButton = uibutton(app.UIFigure, 'push');
            app.MarkButton.ButtonPushedFcn = createCallbackFcn(app, @MarkButtonPushed, true);
            app.MarkButton.Text = 'Mark';
            app.StoreButton = uibutton(app.UIFigure, 'push');
            app.StoreButton.ButtonPushedFcn = createCallbackFcn(app, @StoreButtonPushed, true);
            app.StoreButton.Text = 'Store';
            app.ReflectanceImageButton = uibutton(app.UIFigure, 'push');
            app.ReflectanceImageButton.ButtonPushedFcn = createCallbackFcn(app, @switchToReflectance, true);
            app.ReflectanceImageButton.Text = 'Reflectance Image';
            app.AltitudeImageButton = uibutton(app.UIFigure, 'push');
            app.AltitudeImageButton.ButtonPushedFcn = createCallbackFcn(app, @switchToAltitude, true);
            app.AltitudeImageButton.Text = 'Altitude Image';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoldStandardApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end